classdef SNMFS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMAFSUIFigure                   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CoarseorFineButtonGroup         matlab.ui.container.ButtonGroup
        CoarseButton                    matlab.ui.control.RadioButton
        FineButton                      matlab.ui.control.RadioButton
        GenefilterandnormalizationPanel  matlab.ui.container.Panel
        UpperboundEditFieldLabel        matlab.ui.control.Label
        UpperboundEditField             matlab.ui.control.NumericEditField
        LowerboundEditFieldLabel        matlab.ui.control.Label
        LowerboundEditField             matlab.ui.control.NumericEditField
        RunNormalization_Button         matlab.ui.control.Button
        SNMFdimensionalityreductionPanel  matlab.ui.container.Panel
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        BetaLabel                       matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        MAGICsmoothingCheckBox          matlab.ui.control.CheckBox
        RunSNMF_Button                  matlab.ui.control.Button
        RankEditFieldLabel              matlab.ui.control.Label
        RankEditField                   matlab.ui.control.NumericEditField
        MaxiterEditFieldLabel           matlab.ui.control.Label
        MaxiterEditField                matlab.ui.control.NumericEditField
        toleranceEditFieldLabel         matlab.ui.control.Label
        toleranceEditField              matlab.ui.control.NumericEditField
        calcu_initial_WButton           matlab.ui.control.Button
        SpectralClusterPanel            matlab.ui.container.Panel
        SpectralNumberEditFieldLabel    matlab.ui.control.Label
        SpectralNumberEditField         matlab.ui.control.NumericEditField
        GaussiancorenumbreEditFieldLabel  matlab.ui.control.Label
        GaussiancorenumbreEditField     matlab.ui.control.NumericEditField
        GMButton                        matlab.ui.control.Button
        ClusteringButton                matlab.ui.control.Button
        LambdaEditFieldLabel            matlab.ui.control.Label
        LambdaEditField                 matlab.ui.control.NumericEditField
        MinimumgroupsizeEditFieldLabel  matlab.ui.control.Label
        MinimumgroupsizeEditField       matlab.ui.control.NumericEditField
        ReadfilePanel                   matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        UploadButton                    matlab.ui.control.Button
        DataformatDropDownLabel         matlab.ui.control.Label
        DataformatDropDown              matlab.ui.control.DropDown
        Reset                           matlab.ui.control.Button
        OthersPanel                     matlab.ui.container.Panel
        RunTSNEButton                   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        OutputListBox                   matlab.ui.control.ListBox
        UIAxes                          matlab.ui.control.UIAxes
        Clear                           matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Path = []; % the path of origin data
        FileName = [];%the file name of origin data
        OriginData = {};% store the origin data (only reading operation)
        A_source = [];% store the data that only sums the same gene name
        geneName={};% save the finnal gene name
        cellName= {};% save the cell name or cell label
        A_norm = [];% save normalized data
        genes_used_for_clustering = {};% gene name used for clustering
        logFile = {};%statu of process
        logFile_index=[]; %statu of process
        initial_W = [];
        H = [];
        W = [];
        H_new = [];% save the H after deleting small variance feature
        similar_matrix = [];%save the similarity matrix
        P_mat = []; %save the probability matrix
        Fielder_vector = [];
        eigenVector_SM = [];
        singleValue_bound = [];
        SingleValue = [];
        celltype_label = [];
        mapped_A_by_tSNA = [];
        ing_ = [];
        eitimate_nSpectral = 0;
    end
    
    methods (Access = private)
        
        function read_data(app)
            %This function is used to read the gene expression matrix
            %Note: this Function only used to read .csv, .tsv and .mtx
            
            if ~isempty(app.FileName)
                %% read csv file        
                if strcmpi(app.FileName(end-2:end),'csv')
                    Data_no_change = importdata([app.Path,app.FileName],',',1);
                    A_source_head = Data_no_change.textdata;
                    app.A_source =Data_no_change.data;
                    
                    % Summing the expression of genes of the same name
                    [app.geneName,index_ing,b] = unique(A_source_head(2:end,1),'stable');
                    unique_b= unique(b);
                    app.A_source = app.A_source(index_ing,:);
                    for i = 1 : length(unique_b)
                        if sum(b == unique_b(i))>1
                            app.A_source(i,:) = sum(Data_no_change.data(b == unique_b(i),:));
                        end
                    end
                    app.cellName = A_source_head(1,2:end);
                    return;
                end
                
                %% read tsv file
                if  strcmpi(app.FileName(end-2:end),'tsv')
                    Data_no_change = importdata([app.Path,app.FileName],'\t',1);
                    A_source_head = Data_no_change.textdata;
                    app.A_source =Data_no_change.data;
                
                    % Summing the expression of genes of the same name
                    [app.geneName,index_ing,b] = unique(A_source_head(2:end,1),'stable');
                    unique_b= unique(b);
                    app.A_source = app.A_source(index_ing,:);
                    for i = 1 : length(unique_b)
                        if sum(b == unique_b(i))>1
                            app.A_source(i,:) = sum(Data_no_change.data(b == unique_b(i),:));
                        end
                    end
                    app.cellName = A_source_head(1,2:end);
                    return
                end
            else    
            
                %% read mtx file
                fid = fopen([app.Path,'barcodes.tsv']);
                app.cellName = textscan(fid, '%s','delimiter', '\t');
                fclose(fid);
                app.cellName = app.cellName{1};
                
                fileID = fopen([app.Path,'matrix.mtx']);
                C = textscan(fileID,'%n %n %n','CommentStyle','%');
                fclose(fileID);
                app.A_source = sparse(C{1}(2:end),C{2}(2:end),C{3}(2:end),C{1}(1),C{2}(1));
                clear C;
                
                fid = fopen([app.Path,'genes.tsv']);
                app.geneName = textscan(fid, '%s %s','delimiter', '\t');
                app.geneName = app.geneName{1,1};
                fclose(fid);
                
                % Sum the expression of genes of the same name
                [app.geneName,index_ing,b] = unique(app.geneName(2:end,1),'stable');
                unique_b= unique(b);
                app.A_source = app.A_source(index_ing,:);
                for i = 1 : length(unique_b)
                    if sum(b == unique_b(i))>1
                        app.A_source(i,:) = sum(app.A_source(b == unique_b(i),:));
                    end
                end
             end
        end
        function init(app) 
            app.FileName =[];
            app.Path = [];
            app.OriginData = {};% store the origin data (only reading operation)
            app.A_source = [];% store the data that only sums the same gene name
            app.geneName={};% save the finnal gene name
            app.cellName= {};% save the cell name or cell label
            app.A_norm = [];% save normalized data
            app.genes_used_for_clustering = {};% gene name used for clustering
            app.logFile = {};%statu of process
            app.logFile_index=[]; %statu of process
            app.initial_W = [];
            app.H = [];
            app.W = [];
            app.H_new = [];% save the H after deleting small variance feature
            app.similar_matrix = [];%save the similarity matrix
            app.P_mat = []; %save the probability matrix
            app.Fielder_vector = [];
            app.eigenVector_SM = [];
            app.singleValue_bound = [];
            app.SingleValue = [];
            app.celltype_label = [];
            app.mapped_A_by_tSNA = [];
            app.UploadButton.Enable = 'off';
            app.UpperboundEditField.Enable = 'off';
            app.LowerboundEditField.Enable = 'off';
            app.RunNormalization_Button.Enable = 'off';
            app.AlphaEditField.Enable = 'off';
            app.BetaEditField.Enable = 'off';
            app.MAGICsmoothingCheckBox.Enable = 'off';
            app.RunSNMF_Button.Enable = 'off';
            app.RankEditField.Enable = 'off';
            app.MaxiterEditField.Enable = 'off';
            app.toleranceEditField.Enable = 'off';
            app.calcu_initial_WButton.Enable = 'off';
            app.SpectralNumberEditField.Enable = 'off';
            app.GaussiancorenumbreEditField.Enable = 'off';
            app.GMButton.Enable = 'off';
            app.ClusteringButton.Enable = 'off';
            app.LambdaEditField.Enable = 'off';
            app.RunTSNEButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.MinimumgroupsizeEditField.Value = 0;
            app.MinimumgroupsizeEditField.Enable = 'off';            
            app.eitimate_nSpectral = 0;
            
        end
        function Cout(app,str)
            app.logFile{end + 1} = str;  
            app.OutputListBox.Items = app.logFile;
            app.logFile_index = [app.logFile_index,num2str(length(app.logFile))];
            app.OutputListBox.ItemsData = app.logFile_index;
            app.OutputListBox.Value = app.OutputListBox.ItemsData{end};
            scroll(app.OutputListBox,app.OutputListBox.ItemsData{end});
        end
        function [W , H ,error_new,state] = SNMF_saveMemery_cpu(app)
            %This program is used to implement sparse matrix non-negative decomposition algorithm
            [m , n] = size(app.A_norm);
           
            if app.FineButton.Value == 1
                W = app.initial_W(:,2:app.RankEditField.Value + 1);%+ 0.000001 * rand(m ,k);
            end
            
            if app.CoarseButton.Value == 1
                W = app.initial_W(:,1:app.RankEditField.Value);                
            end
            H =rand(app.RankEditField.Value ,n);
            A_F = sum(sum(app.A_norm.^2));
            error_new = sum(sum(H.*((W'*W)*H))) - 2*sum(sum((H.*(W'*app.A_norm))));
            error_old = 1 + error_new/A_F;
            error_min = error_old;
            error_new = error_old;
            
            alph = app.AlphaEditField.Value;
            beta = app.BetaEditField.Value;
            
            gama = 1 - alph - beta ;
            
            iter = 1;
            count = 0;
            W_old = W;
            H_old = H;
            count_rise = 0;%calculate the number of raising steps;
            state = 0;
             
            bound_error =app.toleranceEditField.Value;
            ones_1_k = ones(1,app.RankEditField.Value);
            A_new_H =[gama*app.A_norm;zeros(1,n)];  
            A_new_W = [gama* (app.A_norm') ; zeros(1,m)];
            Cout(app, sprintf('Iter， error， difference, count, count_rise'));
            while count < 5 && iter<=app.MaxiterEditField.Value
                    W_new = [gama*W ; beta * ones_1_k];        
                    C_mat = (W_new')*W_new;
                    B_mat = (W_new')*A_new_H;
            
                    tol = 10*norm(W_new,1)*length(W_new)*eps;
                    H = SNMF_inner_mex(C_mat,B_mat,tol);
             
                    H_new  = [gama*(H');alph * ones_1_k];      
                    C_mat = (H_new')*H_new;
                    B_mat = (H_new')*A_new_W;
            
                    tol = 10*norm(H_new,1)*length(H_new)*eps;
                    W = SNMF_inner_mex(C_mat,B_mat,tol)';    
                    error_new = sum(sum(H.*((W'*W)*H))) - 2*sum(sum((H.*(W'*app.A_norm))));
                    error_new = 1 + error_new/A_F;
                    plot(app.UIAxes ,iter ,error_new,'*r');
                    error = abs(error_new - error_old);
                    if error_new > (error_old + bound_error)
                        count_rise = count_rise + 1;
                    else
                        count_rise = 0;
                    end
                
                    if error_new < error_min
                         error_min = error_new;
                         W_old = W;
                         H_old = H;
                    end
                    if count_rise>3
                        Cout(app, 'The rank selection of the matrix is larger and trying a smaller rank.'); 
                        error = error_new;
                        W = W_old;
                        H = H_old;
                        return;
                    end
                     
                    if (error<= bound_error)
                        count = count +1;                        
                    else
                        count =0;
                    end
                    
                    Cout(app, sprintf('Iter %d， %f， %0.5g, %d, %d', iter, error_new, abs(error_new - error_old),count,count_rise));
                    error_old = error_new;
            
                    iter = iter+1;
                     pause(0.00001);
                     if iter == app.MaxiterEditField.Value
                        return;
                     end
            end
            W = W_old;
            H = H_old;
            state = 1;
            end
        function Gaussion_classify(app)
             app.H_new = app.H(var(app.H')>0,:);
             app.GaussiancorenumbreEditField.Value = size(app.H_new,1)+1;
             ClusterNumber = app.GaussiancorenumbreEditField.Value;
             app.P_mat=[];    
             rng(1,'twister')
             Sigma = {'diagonal','full'};
             SharedCovariance = {true,false};
             options = statset('Display','off','MaxIter',1000);
             D = app.H_new - mean(app.H_new,2);
             Maha_D = sum(D.*(diag(var(app.H_new,1,2).^(-1))*D));
             [~,index_cell] = sort(Maha_D,'ascend');
             H_sorted = app.H_new(:,index_cell);
             gmfit = fitgmdist(H_sorted',ClusterNumber,'CovarianceType',Sigma{1},...
                                      'SharedCovariance',SharedCovariance{1},'Options',options);
             [~,~,P_posterior] = cluster(gmfit,app.H_new');
             app.P_mat = [app.P_mat,P_posterior];
        
            for i = 1:49
                rng(i+1,'twister'); % For reproducibility
                gmfit = fitgmdist(H_sorted',ClusterNumber ,'Start','plus','CovarianceType','diagonal',...
                                           'SharedCovariance',true,'Options',options);
                [~,~,P_posterior] = cluster( gmfit,app.H_new');  
                app.P_mat = [app.P_mat,P_posterior];
            end
            app.P_mat = app.P_mat * sqrt(0.02); 
        end
        function x_new = zero_norm_inner(app,x_old,A_diff,Lambda)

            %This  function is  used to L0 optimization
            n_old = length(x_old);
            abs_x_old = abs(x_old);
            n_old_1 = n_old -1;
            cvx_begin quiet
                variable x_new(n_old)
                variable y(n_old_1)
                minimize((1 - Lambda)*norm(x_old - x_new , 2)+Lambda* norm(y , 1))
                subject to
                y == A_diff*x_new;
            cvx_end
        end
        
        function y = dealWrongCluster(app,x,x_old)
            x_old = roundn(x_old ,-4);
            x= roundn(x ,-3);%Rounding off    
            y = x;
            x_unique = unique(x,'stable');
                %%{
            for i = 1:length(x_unique)
                    if length(unique(x_old(x== x_unique(i),1))) <= 3 
                             continue
                    end      
                    x_ing = x_old(x== x_unique(i),1);
                    length_x = sum(x== x_unique(i));
                    index_1 = max(floor(0.25*length_x),2);
                    index_2 = max(floor(0.75*length_x),4);
                    x_ing = x_ing(index_1:index_2) - x_ing(index_1);
                    sig = 2*mean(x_ing)/length(x_ing);
                    if abs(sig) > 0.012
                        if i == 1
                            y(x==  x_unique(i),1) = x_unique(i+1);
                            x_unique(i) = x_unique(i+1);
                            continue;
                        end
                        if i==length(x_unique)
                            y(x==  x_unique(i),1) = x_unique(i-1);
                            x_unique(i) = x_unique(i-1);
                            continue;
                        end
                        if x_unique(i) - x_unique(i-1) >x_unique(i+1) - x_unique(i)
                            y(x==  x_unique(i),1) = x_unique(i+1);
                            x_unique(i) = x_unique(i+1);
                        else
                            y(x==  x_unique(i),1) = x_unique(i-1);
                            x_unique(i) = x_unique(i-1);
                        end
            
                    end
                            
            end 
                    %}   


        end    
        function x_new2 = calcu_FielderVector(app,V_s,lambda , figure_handle)
            %This function is used to calculate L0 Approach of V_s
            % V_s: is a vector will be approached
            % lambda: (scalar)  L0 parameter
            % figure_handle: handle of figure 
                n_old = length(V_s);
                index_i = [1:n_old-1,1:n_old-1];
                index_j = [1:n_old-1,2:n_old];
                value_A = [-ones(1,n_old-1),ones(1,n_old-1)];
                A_diff = sparse(index_i , index_j , value_A);
                clear index_i index_j value_A         
                t = 1:n_old;
                hold(figure_handle ,'on');
                plot(figure_handle, t ,V_s,'xk');   
                pause(0.00001)
                 x_old_ = V_s;
                error = 1;
                while error > 0.0001       
                    y = A_diff * x_old_;
                    block_size = app.MinimumgroupsizeEditField.Value;
                    if  block_size == 0                
                        block_size =max(ceil(0.01*length(y)),6);
                    end
                    y(1:block_size,1) = 0;
                    y(end - block_size+1:end,1) = 0;
                    for j  = block_size : length(y)-block_size
                        if y(j,1) ~= max(y(j-block_size+1:j+block_size,1))
                            y(j) =0.0;
                        end
                    end
                    wight = 1./(y.^1.4+0.0000000001);
                    wight = diag(wight);
                    A_diff_wight = wight*A_diff;      
                    x_new2 = zero_norm_inner(app, x_old_,A_diff_wight,lambda);
                    error = norm(x_new2 - x_old_ , 2);
                    x_old_ = x_new2;
                end
                if strcmp(app.CoarseorFineButtonGroup.SelectedObject.Text, 'Coarse')
                    x_new2 = dealWrongCluster(app,x_new2, V_s);
                end                    
                    plot(figure_handle, t ,x_new2,'r');
                    hold(figure_handle ,'off');  
        end
        
        function caulecte_similarMat(app)
            Cout(app,'It is calculating similar matrix')            
            beginTime = tic;
            try
                app.similar_matrix = [];                
                Gaussion_classify(app); 
                app.similar_matrix = app.P_mat*app.P_mat';                 
                Cout(app, sprintf('Total %f features are selected' , size(app.H_new,1)));                         
            catch ErrorInfo %caught error is an MException object
                disp(ErrorInfo);
                Cout(app,'gaussian mixed failed');                
                app.StatusLamp.Color = [0,1,0] ;                
                return;
            end
            Cout(app,sprintf('Calculating similar matrix costs %f s',toc(beginTime)));           
            
            sum_row = sum(app.similar_matrix,2);
            Lapace_similar_Matrix = diag(sum_row)-app.similar_matrix;
            La_new = Lapace_similar_Matrix.*(abs(Lapace_similar_Matrix) > 0.0001);
            clear Lapace_similar_Matrix;
            
            Cout(app,'It is svd');
                      
            beginTime = tic;           
             La_2 = max(sum_row)*eye(size(La_new,1)) - La_new;
             [singleValue_U,eigen_SM]  = svds(gpuArray(La_2),45);
             eigen_SM= gather(eigen_SM);
             singleValue_U = gather(singleValue_U); 
             eigenValue_V=  max(sum_row) - diag(eigen_SM);
             app.eigenVector_SM = singleValue_U;
             clear singleValue_U 

             
             app.eigenVector_SM = app.eigenVector_SM(:,~isnan(eigenValue_V));
             eigenValue_V  = eigenValue_V (~isnan(eigenValue_V));
             [eigenValue_V ,index] = sort(abs(eigenValue_V),'ascend');             
             eigenValue_V = eigenValue_V/max(sum_row);
%              eigenValue_V =eigenValue_V/gather(max_value);
             app.SingleValue = eigenValue_V;
             app.eigenVector_SM = app.eigenVector_SM(:,index);            
        
            value_P = max(app.eigenVector_SM, 1e-16);
            value_N = max(-app.eigenVector_SM, 1e-16);            
            n_P_big = sum(value_P.* value_P);
            n_N_big = sum(value_N.*value_N);
            if sum(n_P_big <n_N_big)>0 
                app.eigenVector_SM(:,n_P_big <n_N_big) = -app.eigenVector_SM(:,n_P_big <n_N_big);
            end
            Cout(app,sprintf('svd costs %f s',toc(beginTime)))


             app.Fielder_vector = [];
             t = 1:size(app.eigenVector_SM,1);
             figure('Name','Eigenvectors of similar matrix');
             for i = 1 :min(9 , size(app.eigenVector_SM ,2))
                subplot(3,3,i)
                plot(t,sort(app.eigenVector_SM(:,i)),'.k','MarkerSize',10)
%                 plot(t,sort(app.eigenVectorSimilarMat(:,i)))
                title(['\lambda',num2str(i),' : ',num2str(eigenValue_V(i))]);
             end
            addpath('C:\Software\cvx\builtins');
            addpath('C:\Software\cvx\commands');
            addpath('C:\Software\cvx\functions');
            addpath('C:\Software\cvx\lib');  
            addpath('C:\Software\cvx\structures');
            addpath('C:\Software\cvx\functions\vec_');
            addpath('C:\Software\cvx');
%                 x_ing = 1- eigen_SM_vector(1:49);
            n_eigen =min( 30 , size(app.eigenVector_SM ,2));
            x_ing  = eigenValue_V(1:n_eigen);
            x_old_ = x_ing;
            n_old  = length(x_old_);
            index_i = [1:n_old-1,1:n_old-1];
            index_j = [1:n_old-1,2:n_old];
            value_A = [-ones(1,n_old-1),ones(1,n_old-1)];
            A_diff = sparse(index_i , index_j , value_A);
            clear  index_i index_j value_A;
            error = 1;
            while error > 0.0001       
                y = A_diff * x_old_;
                wight = 1./(y+0.00000001);
                wight = diag(wight);
                A_diff_wight = wight*A_diff;
%                          x_new = zero_norm_inner(x_old_,A_diff_wight,0.002);%SNMF
                x_new = zero_norm_inner(app,x_old_,A_diff_wight,0.010);%NMF
                error = norm(x_new - x_old_ , 2);
                x_old_ = x_new;                        
            end
            rmpath('C:\Software\cvx\builtins');
            rmpath('C:\Software\cvx\commands');
            rmpath('C:\Software\cvx\functions');
            rmpath('C:\Software\cvx\lib');                
            rmpath('C:\Software\cvx\structures');
            rmpath('C:\Software\cvx\functions\vec_');
            rmpath('C:\Software\cvx');  
            
            figure("Name",'the distribution of eigenvalue in the similarity matrix')
            hold on
            plot(1:n_eigen,x_ing,'.')
            x_old_ = roundn(x_old_ ,-3);%Rounding off
            plot(1:n_eigen,x_old_)
            hold off
            app.singleValue_bound = x_old_(2);
%             n_eigenVector = sum(x_ing <(app.singleValue_bound+0.0015));%SNMF
%             n_eigenVector = sum(x_ing < (app.singleValue_bound+0.015));%NMF
            n_eigenVector = sum(x_ing < (1.5*app.singleValue_bound));%NMF
            F_v = mean(app.eigenVector_SM(:,1:n_eigenVector),2);
            if max(F_v) - min(F_v) < 1e-4
                    app.eigenVector_SM(:,1) = 0;
                    n_eigenVector = n_eigenVector +1;                    
            end

            app.SpectralNumberEditField.Value = min(max(n_eigenVector,2),app.RankEditField.Value-1);
            app.Fielder_vector = mean(app.eigenVector_SM(:,1:app.SpectralNumberEditField.Value),2); 
            
            [x_old, ~ ] = sort(app.Fielder_vector);            
            y = x_old(2:end) - x_old(1:end-1);
            block_size=max(floor( 0.025*length(x_old)),3);
            for j  =block_size : length(y)-block_size
                if y(j,1) ~= max(y(j-block_size+1:j+block_size,1))
                    y(j) =0;
                end
           end
             
            if ~ isempty(app.UIAxes)
                cla(app.UIAxes);
            end            
%             title(app.UIAxes, 'the difference of Fiedler vector' ,'Color', 'm');
%             xlabel(app.UIAxes, 'idx');
%             ylabel(app.UIAxes, 'Value'); 
%             y_new = sort(y ,'descend');
%             num_diff = min(25,length(y_new));
%             app.UIAxes.XLim = [0 ,25];
%             hold(app.UIAxes,'on')
%             for index_bar = 1 : num_diff
%                 plot(app.UIAxes , [index_bar ,index_bar],[ 0, y_new(index_bar)],'LineWidth',2,'Color' ,'r')
%             end
%             hold(app.UIAxes,'off')

% K = compute_kernel(app, x_old, 'k', 15, 'a', 15, 'distfun', 'euclidean');
%             
%             % row stochastic
%             P = bsxfun(@rdivide, K, sum(K,2));
% x_old = (P^5)*x_old;
% app.H = x_old;
% x_old = MAGIC_PCA(app)';
            app.UIAxes.XLim = [0 ,inf];
            cla(app.UIAxes);
            title(app.UIAxes, 'the vector of the smallest no zero eigenvalue' ,'Color', 'm');
            xlabel(app.UIAxes, 'idx feature');
            ylabel(app.UIAxes, 'Value');            
            t = 1:length(x_old);
            plot(app.UIAxes, t ,x_old,'.k');
        end
        function SNMF_visual(app, A , class_ ,mapping,state_lagend,axes_)
            %This function is used for visualization by SNMF 
            %A is n_simple * n_feature
            %class_ is label of cell
            %Select the features with the first few variances to visualize the data.
            color_bar=[0.109803921568627,0.901960784313726,1;
                1,0.203921568627451,1;
                0,0.537254901960784,0.254901960784314;
                1,0.290196078431373,0.274509803921569;                
                0,0.435294117647059,0.650980392156863;
                0.639215686274510,0,0.349019607843137;1,0.858823529411765,0.898039215686275;
                0.478431372549020,0.286274509803922,0;0,0,0.650980392156863;0.388235294117647,1,0.674509803921569;
                0.717647058823529,0.592156862745098,0.384313725490196;0,0.301960784313725,0.262745098039216;
                0.560784313725490,0.690196078431373,1;0.600000000000000,0.490196078431373,0.529411764705882;
                0.352941176470588,0,0.0274509803921569;
                0.7,0.7,0.701960784313726;0.105882352941176,0.266666666666667,0;
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                0.494117647058824,0.827450980392157,0.474509803921569;0.00392156862745098,0.172549019607843,0.345098039215686];
%             color_bar = [32,178,170; 143,188,143;127,255,0;0,128,0;0,255,127]/256;
            
            
            shape_point ={
            '+'	%Plus sign
            '.'
            'v'
            '*'
            'x'
            'p'  %Hexagram
            'd'
            'o'	%Circle
            's'	%Square
            '^'	%Upward-pointing triangle
            
            };
%           shape_point ={            
%             'v'
%             'p'  %Hexagram
%             'o'	%Circle
%             's'	%Square
%             '^'	%Upward-pointing triangle            
%             };
            [~ , n ] = size(A);%get the size of H
            
                if n >=3
                    hold on
                    label_unique = unique(class_(: , 1),'stable');
                    std_feature = std(A , 1);
                    [~ ,idx] =sort(std_feature ,'descend');
                    A = A(:, idx(1:3));
                    for i = 1 :length(label_unique)            
                        scatter3(A(class_(: , 1)==label_unique(i),1),A(class_(: , 1)==label_unique(i),2),A(class_(: , 1)==label_unique(i),3),...
                                'MarkerEdgeColor',color_bar(i,:),...
                                'MarkerFaceColor',color_bar(i,:))
                    end
                    hold off
                else
                    switch n
                        case 1
                             t = 1:size(A,1);
                             [Field_V,index_c] = sort(A(:,1),'ascend');
                             Label_ing = class_(index_c,1);
                             hold on
                             for id_class = 1 : length(unique(class_(:,1)))
                                 plot(t(Label_ing==id_class),Field_V(Label_ing==id_class,1),'o',...
                                       'MarkerEdgeColor',color_bar(id_class,:),'MarkerFaceColor',color_bar(id_class,:),'MarkerSize',3);
                             end
                             hold off
                        case 2
                            X = A(:,1);
                            Y = A(:,2);
                            hold on
                            if ~exist('axes_', 'var') || isempty(axes_)
                                    if size(class_,2) ==1
                                        label_unique = unique(class_(: , 1),'stable');
                                        for i = 1 :length(label_unique)
                                                index = class_(: , 1)==label_unique(i);
                    %                             plot(X(index,1),Y(index,1),shape_point{i},'MarkerEdgeColor',color_bar(i,:),'MarkerSize',1)  
                                                plot(X(index,1),Y(index,1),'o','MarkerEdgeColor',color_bar(i,:),'MarkerFaceColor',color_bar(i,:),'MarkerSize',5)
                                        end
                                    else
                                        label_unique = unique(class_(: , 1),'stable');
                                        for i = 1:length(unique(class_(:,2)))                      
                                              for j =1 : length(unique(class_(:,1)))
                                                     index = class_(: , 2)==i&class_(: , 1)==label_unique(j);
                                                     if sum(index)>1
                                                        plot(X(index,1),Y(index,1),shape_point{i},'MarkerEdgeColor',color_bar(j,:),'MarkerSize',5)     
                                                     end
                                              end                 
            
                                        end                      
            
                                    end
                            else
                                 if size(class_,2) ==1
                                        label_unique = unique(class_(: , 1),'stable');
                                        for i = 1 :length(label_unique)
                                                index = class_(: , 1)==label_unique(i);
                                                plot(axes_,X(index,1),Y(index,1),shape_point{i},'MarkerEdgeColor',color_bar(i,:))  
            %                                     plot(axes_ , X(index,1),Y(index,1),'o','MarkerEdgeColor',color_bar(i,:),'MarkerFaceColor',color_bar(i,:),'MarkerSize',5)
                                        end
                                 else
                                        label_unique = unique(class_(: , 1),'stable');
                                        for i = 1:length(unique(class_(:,2)))                      
                                              for j =1 : length(unique(class_(:,1)))
                                                     index = class_(: , 2)==i&class_(: , 1)==label_unique(j);
                                                     if sum(index)>1
                                                        plot(axes_ , X(index,1),Y(index,1),shape_point{i},'MarkerEdgeColor',color_bar(j,:))     
                                                     end
                                              end               
                                        end                      
                                 end                   
                            end                
                            hold off
                    end
                end    
            
                
             if exist('mapping', 'var')&& ~isempty(mapping)    
                    if ~exist('axes_', 'var') || isempty(axes_)
                        legend(mapping)
                    else
                        legend(axes_,mapping)
                    end 
                    return;
             end
            
            if exist('state_lagend', 'var') && ~isempty(state_lagend)
                if state_lagend
                    if ~exist('axes_', 'var') || isempty(axes_)
                            legend(mapping)
                    else
                            legend(axes_,mapping)
                    end
                    return;
                end      
            end 
            label_unique = unique(class_(: , 1),'stable');
            label_cell = {length(label_unique)};
            for i = 1 : length(label_unique)
                label_cell{i} = ['celltype',num2str(i)];
            end
            legend(label_cell)
            
                    
end

    end


    %% MAGIC smoothing
    methods (Access = public)
        function K = compute_kernel(app,data, varargin)
            % K = computer_alpha_kernel_sparse(data, varargin)
            % Computes sparse alpha-decay kernel
            % varargin: 
            %   'npca' (default = [], no PCA)
            %       Perform fast random PCA before computing distances
            %   'k' (default = 5)
            %       k for the knn distances for the locally adaptive bandwidth
            %   'a' (default = 10)
            %       The alpha exponent in the alpha-decaying kernel
            %   'distfun' (default = 'euclidean')
            %       Input distance function
            k = 5;
            a = 10;
            npca = [];
            distfun = 'euclidean';
            % get the input parameters
            if ~isempty(varargin)
                for j = 1:length(varargin)
                    % k nearest neighbora
                    if strcmp(varargin{j}, 'k')
                        k = varargin{j+1};
                    end
                    % alpha
                    if strcmp(varargin{j}, 'a')
                        a = varargin{j+1};
                    end
                    % npca to project data
                    if strcmp(varargin{j}, 'npca')
                        npca = varargin{j+1};
                    end
                    % distfun
                    if strcmp(varargin{j}, 'distfun')
                        distfun = varargin{j+1};
                    end
                end
            end
            
            th = 1e-4;
            
            k_knn = k * 20;
            
            bth=(-log(th))^(1/a);
            
            disp 'Computing alpha decay kernel:'
            
            N = size(data, 1); % number of cells
            
            if ~isempty(npca)
                disp '   PCA'
                data_centr = bsxfun(@minus, data, mean(data,1));
                [U,~,~] = randPCA(data_centr', npca); % fast random svd
                %[U,~,~] = svds(data', npca);
                data_pc = data_centr * U; % PCA project
            else
                data_pc = data;
            end
            
            disp(['Number of samples = ' num2str(N)])
            
            % Initial knn search and set the radius
            disp(['First iteration: k = ' num2str(k_knn)])
            [idx, kdist]=knnsearch(data_pc,data_pc,'k',k_knn,'Distance',distfun);
            epsilon=kdist(:,k+1);
            
            % Find the points that have large enough distance to be below the kernel
            % threshold
            below_thresh=kdist(:,end)>=bth*epsilon;
            
            idx_thresh=find(below_thresh);
            
            if ~isempty(idx_thresh) 
                K=exp(-(kdist(idx_thresh,:)./epsilon(idx_thresh)).^a);
                K(K<=th)=0;
                K=K(:);
                i = repmat(idx_thresh',1,size(idx,2));
                i = i(:);
                idx_temp=idx(idx_thresh,:);
                j = idx_temp(:);
            end
            
            disp(['Number of samples below the threshold from 1st iter: ' num2str(length(idx_thresh))])
            
            % Loop increasing k by factor of 20 until we cover 90% of the data
            while length(idx_thresh)<.9*N
                k_knn=min(20*k_knn,N);
                data_pc2=data_pc(~below_thresh,:);
                epsilon2=epsilon(~below_thresh);
                disp(['Next iteration: k= ' num2str(k_knn)])
                [idx2, kdist2]=knnsearch(data_pc,data_pc2,'k',k_knn,'Distance',distfun);
                
            %     Find the points that have large enough distance
                below_thresh2=kdist2(:,end)>=bth*epsilon2;
                idx_thresh2=find(below_thresh2);
                
                if ~isempty(idx_thresh2)
                    K2=exp(-(kdist2(idx_thresh2,:)./epsilon2(idx_thresh2)).^a);
                    K2(K2<=th)=0;
                    idx_notthresh=find(~below_thresh);
                    i2=repmat(idx_notthresh(idx_thresh2)',1,size(idx2,2));
                    i2=i2(:);
                    idx_temp=idx2(idx_thresh2,:);
                    j2=idx_temp(:);
                    
                    i=[i; i2];
                    j=[j; j2];
                    K=[K; K2(:)];
            %         Add the newly thresholded points to the old ones
                    below_thresh(idx_notthresh(idx_thresh2))=1;
                    idx_thresh=find(below_thresh);
                end
                disp(['Number of samples below the threshold from the next iter: ' num2str(length(idx_thresh))])
            end
            
            % Radius search for the rest
            if length(idx_thresh)<N
                disp(['Using radius based search for the rest'])
                data_pc2=data_pc(~below_thresh,:);
                epsilon2=epsilon(~below_thresh);
                [idx2, kdist2]=rangesearch(data_pc,data_pc2,bth*max(epsilon2),'Distance',distfun);
                idx_notthresh=find(~below_thresh);
                for m=1:length(idx2)
                    i=[i; idx_notthresh(m)*ones(length(idx2{m}),1)];
                    j=[j; idx2{m}'];
                    K2=exp(-(kdist2{m}./epsilon2(m)).^a);
                    K2(K2<=th)=0;
                    K=[K; K2(:)];
                end
                
            end
            
            % Build the kernel
            K = sparse(i, j, K);
            
            disp '   Symmetrize affinities'
            K = K + K';
            disp '   Done computing kernel'
        end
        function W = compute_operator(app,data, varargin)
            % W = compute_operator(data, varargin)
            %   computes diffusion operator W
            % varargin:
            %   'npca' (default = 20)
            %       perform fast random PCA before computing distances
            %   'ka' (default = 10)
            %       k of adaptive kernel
            %       0 for non-adaptive (standard gaussian) kernel with bandwidth
            %       epsilon
            %   'k' (default = 30)
            %       k of kNN graph
            %   'epsilon' (default = 1)
            %       kernel bandwith, if epsilon = 0 kernel will be uniform, i.e.
            %       unweighted kNN graph (ka will be ignored)
        
            % set up default parameters
            k = 12;
            ka = 4;
            npca = 100;
            epsilon = 1;
            lib_size_norm = true;
            log_transform = false;
        
            % get the input parameters
            if ~isempty(varargin)
                for j = 1:length(varargin)
                    % k nearest neighbor
                    if strcmp(varargin{j}, 'ka')
                        ka = varargin{j+1};
                    end
                    % for knn-autotune
                    if strcmp(varargin{j}, 'k')
                        k = varargin{j+1};
                    end
                    % epsilon
                    if strcmp(varargin{j}, 'epsilon')
                        epsilon = varargin{j+1};
                    end
                    % npca to project data
                    if strcmp(varargin{j}, 'npca')
                        npca = varargin{j+1};
                    end
                    % library size normalization
                    if strcmp(varargin{j}, 'lib_size_norm')
                        lib_size_norm = varargin{j+1};
                    end
                    % log transform
                    if strcmp(varargin{j}, 'log_transform')
                        log_transform = varargin{j+1};
                    end
                end
            end
        
            % library size normalization
            if lib_size_norm
                disp 'Library size normalization'
                libsize  = sum(data,2);
                data = bsxfun(@rdivide, data, libsize) * median(libsize);
            end
        
            % log transform
            if log_transform
                disp(['Log transform, with pseudo count ' num2str(pseudo_count)]);
                data = log(data + pseudo_count);
            end
        
            N = size(data, 1); % number of cells
        
            if ~isempty(npca)
                disp 'PCA'
                data_centr = bsxfun(@minus, data, mean(data,1));
                [U,~,~] = randPCA(data_centr', npca); % fast random svd
                %[U,~,~] = svds(data', npca);
                data_pc = data_centr * U; % PCA project
            else
                data_pc = data;
            end
        
            disp 'Computing distances'
            [idx, dist] = knnsearch(data_pc, data_pc, 'k', k);
        
            disp 'Adapting sigma'
            dist = bsxfun(@rdivide, dist, dist(:,ka));
        
            i = repmat((1:N)',1,size(idx,2));
            i = i(:);
            j = idx(:);
            s = dist(:);
            if epsilon > 0
                W = sparse(i, j, s);
            else
                W = sparse(i, j, ones(size(s))); % unweighted kNN graph
            end
        
            disp 'Symmetrize distances'
            W = W + W';
        
            if epsilon > 0
                disp 'Computing kernel'
                [i,j,s] = find(W);
                i = [i; (1:N)'];
                j = [j; (1:N)'];
                s = [s./(epsilon^2); zeros(N,1)];
                s = exp(-s);
                W = sparse(i,j,s);
            end
        
            disp 'Markov normalization'
            W = bsxfun(@rdivide, W, sum(W,2)); % Markov normalization
        
            disp 'done'
        end
        function [data_opt_t, t_opt]  = compute_optimal_t(app,data, DiffOp, varargin)

            t_max = 32;
            make_plot = true;
            th = 1e-3;
            data_opt_t = [];
        
            if ~isempty(varargin)
                for j = 1:length(varargin)
                    if strcmp(varargin{j}, 't_max')
                        t_max = varargin{j+1};
                    end
                    if strcmp(varargin{j}, 'make_plot')
                        make_plot = varargin{j+1};
                    end
                    if strcmp(varargin{j}, 'th')
                        th = varargin{j+1};
                    end
                end
            end
        
            data_prev = data;
            if make_plot
                error_vec = nan(t_max,1);
                for I=1:t_max
                    disp(['t = ' num2str(I)]);
                    data_curr = DiffOp * data_prev;
                    error_vec(I) = procrustes(data_prev, data_curr);
                    if error_vec(I) < th && isempty(data_opt_t)
                        data_opt_t = data_curr;
                    end
                    data_prev = data_curr;
                end
                t_opt = find(error_vec < th, 1, 'first');
        
                figure;
                hold all;
                plot(1:t_max, error_vec, '*-');
                plot(t_opt, error_vec(t_opt), 'or', 'markersize', 10);
                xlabel 't'
                ylabel 'error'
                axis tight
                ylim([0 ceil(max(error_vec)*10)/10]);
                plot(xlim, [th th], '--k');
                legend({'y' 'optimal t' ['y=' num2str(th)]});
                set(gca,'xtick',1:t_max);
                set(gca,'ytick',0:0.1:1);
            else
                for I=1:t_max
                    disp(['t = ' num2str(I)]);
                    data_curr = DiffOp * data_prev;
                    error = procrustes(data_prev, data_curr);
                    if error < th
                        t_opt = I;
                        data_opt_t = data_curr;
                        break
                    end
                    data_prev = data_curr;
                end
            end
        
            disp(['optimal t = ' num2str(t_opt)]);
        end
        function pc_imputed = MAGIC_PCA(app, varargin)
            % run_magic  Run MAGIC for imputing and denoising of single-cell data
            %   [pc_imputed, U, pc] = run_magic(data, varargin) runs MAGIC on data (rows:
            %   cells, columns: genes) with default parameter settings and returns the
            %   imputed data in a compressed format.
            %
            %   The compressed format consists of loadings (U) and imputed principal
            %   components (pc_imputed). To obtain gene values form this compressedf format
            %   either run project_genes.m or manually project (pc_imputed * U') either all
            %   genes or a subset (pc_imputed * U(idx,:)'). Also returned are the original
            %   principal components (pc);
            %
            %   Since pc_imputed and U are both narrow matrices the imputed data can be
            %   stored in a memory efficient way, without having to store the dense
            %   matrix.
            %
            %   Supplied data can be a sparse matrix, in which case MAGIC will be more
            %   memory efficient.
            %
            %   [...] = phate(data, 'PARAM1',val1, 'PARAM2',val2, ...) allows you to
            %   specify optional parameter name/value pairs that control further details
            %   of PHATE.  Parameters are:
            %
            %   'npca' - number of PCA components to do MAGIC on. Defaults to 100.
            %
            %   'k' - number of nearest neighbors for bandwidth of adaptive alpha
            %   decaying kernel or, when a=[], number of nearest neighbors of the knn
            %   graph. For the unweighted kernel we recommend k to be a bit larger,
            %   e.g. 10 or 15. Defaults to 10.
            %
            %   'a' - alpha of alpha decaying kernel. when a=[] knn (unweighted) kernel
            %   is used. Defaults to 15.
            %
            %   't' - number of diffusion steps. Defaults to [] wich autmatically picks
            %   the optimal t.
            %
            %   'distfun' - Distance function used to compute kernel. Defaults to
            %   'euclidean'.
            %
            %   'make_plot_opt_t' - Boolean flag for plotting the optimal t analysis.
            %   Defaults to true.
            k = 15;
            a = 15;
            t = [];
            distfun = 'euclidean';
            make_plot_opt_t = true;
            
            % get input parameters
            for i=1:length(varargin)
                % k for knn adaptive sigma
                if(strcmp(varargin{i},'k'))
                   k = lower(varargin{i+1});
                end
                % a (alpha) for alpha decaying kernel
                if(strcmp(varargin{i},'a'))
                   a = lower(varargin{i+1});
                end
                % diffusion time
                if(strcmp(varargin{i},'t'))
                   t = lower(varargin{i+1});
                end
                % npca
                if(strcmp(varargin{i},'npca'))
                   npca = lower(varargin{i+1});
                end
                % make plot optimal t
                if(strcmp(varargin{i},'make_plot_opt_t'))
                   make_plot_opt_t = lower(varargin{i+1});
                end
            end
            
            % compute kernel
            disp 'computing kernel'
            K = compute_kernel(app, app.H', 'k', k, 'a', a, 'distfun', distfun);
            
            % row stochastic
            P = bsxfun(@rdivide, K, sum(K,2));
            
            % optimal t
            if isempty(t)
                disp 'imputing using optimal t'
                pc_imputed = compute_optimal_t(app, app.H', P, 'make_plot', make_plot_opt_t);
            else
                disp 'imputing using provided t'
                pc_imputed = app.H';
                for I=1:t
                    disp(['t = ' num2str(I)]);
                    pc_imputed = P * pc_imputed;
                end
            end
            disp 'done.'
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           Screen_size = get(0,'ScreenSize');     
           UI_size = [min(1200,Screen_size(3)*0.9),min(Screen_size(4)*0.9,750)];
%            UI_size = [Screen_size(3)*0.5,Screen_size(4)*0.7];
           app.SMAFSUIFigure.Position = floor([Screen_size(3)/2 - UI_size(1)/2,Screen_size(4)/2-UI_size(2)/2,UI_size(1),UI_size(2)]);       
           
           UI_size = app.SMAFSUIFigure.Position(3:4); 
           Right_SZ = [UI_size(1)-300,UI_size(2)];
           
           app.GridLayout.RowHeight = {UI_size(2)};
           app.GridLayout.ColumnWidth = {300, Right_SZ(1)}; 
           
           app.UIAxes.Position = floor([0.03*Right_SZ(1),0.3*Right_SZ(2),0.93*Right_SZ(1),0.66*Right_SZ(2)]);
           app.OutputListBox.Position = floor([0.078*Right_SZ(1),0.02*Right_SZ(2),0.87*Right_SZ(1),0.25*Right_SZ(2)]);
           app.Clear.Position = floor([0.03*Right_SZ(1),0.25*Right_SZ(2),0.05*UI_size(2),0.05*UI_size(2)]);
           app.StatusLamp.Position = floor([0.95*Right_SZ(1),0.94*Right_SZ(2),0.03*Right_SZ(1),0.03*Right_SZ(2)]);
           app.StatusLampLabel.Position = floor([0.89*Right_SZ(1),0.94*Right_SZ(2),0.05*Right_SZ(1),0.03*Right_SZ(2)]);


           len_Pannels = [app.CoarseorFineButtonGroup.Position(4),app.ReadfilePanel.Position(4),...
               app.GenefilterandnormalizationPanel.Position(4), app.SNMFdimensionalityreductionPanel.Position(4),....
               app.SpectralClusterPanel.Position(4), app.OthersPanel.Position(4)];
           
           len_Pannels = cumsum(len_Pannels)+19;    
               
           space_Pannels =  max((UI_size(2) - len_Pannels(end))/(length(len_Pannels)+1),0);
           
           UI_size(2) = max(UI_size(2), len_Pannels(end));
           app.CoarseorFineButtonGroup.Position(2) = UI_size(2)-len_Pannels(1)- 1*space_Pannels;
           app.ReadfilePanel.Position(2) = UI_size(2) -len_Pannels(2)- 2*space_Pannels;
           app.GenefilterandnormalizationPanel.Position(2) = UI_size(2)-len_Pannels(3)- 3*space_Pannels;
           app.SNMFdimensionalityreductionPanel.Position(2) = UI_size(2)-len_Pannels(4)- 4*space_Pannels;
           app.SpectralClusterPanel.Position(2) = UI_size(2)-len_Pannels(5)- 5*space_Pannels;
           app.OthersPanel.Position(2) = UI_size(2)-len_Pannels(6)- 6*space_Pannels; 
        end

        % Button pushed function: OpenButton
        function OpenButton_Pushed(app, event)
           init(app);
           app.StatusLamp.Color = 'r';   
           app.ClearPushed(app); 
           csv_or_tsv = {'*.csv;*.tsv','scRNA-seq data (*.csv;*.tsv;)';'*.*',  'All Files (*.*)'};               
           x10 =    {'*.tsv;*.mtx','scRNA-seq data (*.csv;*.mtx;)';'*.*',  'All Files (*.*)'};
           
           if strcmpi(app.DataformatDropDown.Value,'10X')
               [file,path] = uigetfile( x10,'Select "10X" file, should include "barcodes.tsv", "genes.tsv" and "matrix.mtx"','MultiSelect', 'on');
               
               if isequal(file,0)
                   message = 'Please select "10X" file, must include "barcodes.tsv", "genes.tsv" and "matrix.mtx".';
                   uialert(app.SMAFSUIFigure,message,'info',...
                   'Icon','info');   
                   app.StatusLamp.Color = 'g';
                   return
               end
               
               app.RightPanel.Title = path;
               app.Path = path;
               file_must = {'matrix.mtx','barcodes.tsv','genes.tsv'};

               if ischar(file)
                    file = {file};
               end
               
               file_str = 'Selected ';
               for i = 1 : length(file)
                    file_str = [file_str, '"', file{i},'" '];
               end
               Cout(app,[file_str,'.'])
               
               if ~all(ismember(file_must,file))       
                    message = ['Do not select ', file_must(~ismember(file_must,file))];
                    uialert(app.SMAFSUIFigure,message,'Warning',...
                    'Icon','warning');
                    app.StatusLamp.Color = 'g';
                    return;
               end               
              Cout(app,sprintf('**Selected 10x data in %s.**',app.Path)) 
           else
               [app.FileName,app.Path] = uigetfile(csv_or_tsv,'Select "gene X cell" file');  
               
               if isequal(app.FileName,0)      
                   message = 'Please select scRNA-seq data file, genes in rows and cells in columns.';
                   uialert(app.SMAFSUIFigure,message,'info',...
                   'Icon','info');   
                   app.StatusLamp.Color = 'g';
                   return
               end
               
               app.RightPanel.Title = [app.Path,app.FileName];
               id = strfind(app.FileName,'.');
               Cout(app,sprintf('**Selected " %s ".**',app.FileName)); 
               
               if ~ismember(app.FileName(id(end):end),{'.tsv','.csv'})
                    message = ['Do not read ', '" ',app.FileName,' "'];
                    uialert(aapp.SMAFSUIFigure,message,'Warning',...
                    'Icon','warning');
                   return;
               end               
               Cout(app,sprintf('**Selected %s.**',[app.Path, app.FileName])) 
           end          
          
            app.StatusLamp.Color = [0,1,0] ;
            app.UploadButton.Enable = 'on';
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            
            app.StatusLamp.Color = [1,0,0];
            pause(0.001)
            Cout(app,'**It is Uploading file.**')  
           try
                read_data(app);

           catch ME
                 uialert(app.SMAFSUIFigure,ME.message,'Warning',...
                    'Icon','warning');
           end           

            Cout(app, '**Upload Successfully.**');     
            Cout(app, sprintf('The dataset contains %d cells and %d genes.',size(app.A_source,2),size(app.A_source,1)));
            if app.MinimumgroupsizeEditField.Value == 0
                app.MinimumgroupsizeEditField.Value = max(ceil( 0.01*size(app.A_source,2)),6);    
            end
            app.StatusLamp.Color = [0,1,0];
            app.UpperboundEditField.Enable = 'on';
            app.LowerboundEditField.Enable = 'on';
            app.RunNormalization_Button.Enable = 'on';
            
        end

        % Button pushed function: RunNormalization_Button
        function RunNormalization_ButtonPushed(app, event)

            app.StatusLamp.Color = [1,0,0];
            app.mapped_A_by_tSNA = [];
            if isempty(app.A_source)
                app.StatusLamp.Color = [0,1,0];
                return
            end
            pause(0.00001);
            non_rate = sum(app.A_source>0,2)/size(app.A_source,2);           
            app.A_norm = app.A_source(non_rate>0.00&non_rate<1,:);           
            app.genes_used_for_clustering = app.geneName(non_rate>0.00&non_rate<1,1);

            mean_count = mean(app.A_norm,2);    
            mean_value = mean(mean_count);   
            low_bound = (app.LowerboundEditField.Value)*mean_value;
            up_bound = app.UpperboundEditField.Value*mean_value;           
            index = mean_count < up_bound & mean_count >low_bound; 
            app.A_norm = app.A_norm(index,:);%extract the feature of suitable mean value  
            app.genes_used_for_clustering = app.genes_used_for_clustering(index,:);   
            
            Cout(app, sprintf(' After gene filter, the dataset contains %d cells and %d genes.',size(app.A_norm,2),size(app.A_norm,1)));
            
            app.A_norm(isnan(app.A_norm)) = 0;
            app.A_norm = app.A_norm .*  repmat(10000./(sum(app.A_norm) + 0.000000001),size(app.A_norm,1) ,1); 
            
            app.A_norm = log10(app.A_norm + 1);       
            Cout(app, '**Normalization is finished.**');  
            
            app.StatusLamp.Color = [0,1,0];
            app.calcu_initial_WButton.Enable = 'on';
            app.RunTSNEButton.Enable = 'on';
        end

        % Selection changed function: CoarseorFineButtonGroup
        function CoarseorFineButtonGroupSelectionChanged(app, event)
            selectedButton = app.CoarseorFineButtonGroup.SelectedObject;
                 
            if strcmp(selectedButton.Text, 'Coarse')
                app.AlphaEditField.Value = 0.050;
                app.BetaEditField.Value = 0.085;
                app.MAGICsmoothingCheckBox.Value = 0;
            end
            
            if strcmp(selectedButton.Text, 'Fine')
                app.AlphaEditField.Value = 0.01;
                app.BetaEditField.Value = 0.017;
                app.MAGICsmoothingCheckBox.Value = 1;
            end
        end

        % Button pushed function: calcu_initial_WButton
        function calcu_initial_WButtonPushed(app, event)
            app.StatusLamp.Color = [1,0,0];
            Cout(app, '**It is calculating initial W and estimating the rank.**')
            if ~isempty(app.UIAxes)
                cla(app.UIAxes);
                title(app.UIAxes, 'Distribution of SingularValue' ,'Color', 'm');
                xlabel(app.UIAxes, 'idx eigenValue');
                ylabel(app.UIAxes, 'eigenValue');
                app.UIAxes.XLim = [0 50];
            end
            
            pause(0.001)
            app.initial_W = [];
            app.A_norm = sparse(app.A_norm);
            [V,D,~] = svds(gpuArray(app.A_norm),100);
            V = gather(V);
            D = gather(D);
            app.initial_W =V(:,1:min(50,size(V,2)));
            latent_new = diag(D);           
%             app.eigen_vector = latent_new;
            latent_new = latent_new(latent_new > 0);            

            initial_W_P = max(app.initial_W, 0);
            initial_W_N = max(-app.initial_W, 0);
            n_P_big = sum(initial_W_P.* initial_W_P);
            n_N_big = sum(initial_W_N.* initial_W_N);
            
            if sum(n_P_big >=n_N_big)>0
                app.initial_W(:,n_P_big >=n_N_big) = initial_W_P(:,n_P_big >=n_N_big);
            end
            if sum(n_P_big <n_N_big)>0
                app.initial_W(:,n_P_big <n_N_big) = initial_W_N(:,n_P_big <n_N_big);
            end
            n_svd = min(50,length(latent_new)-1);            
            t = 1:n_svd;
            latent_new = latent_new/latent_new(1);
             
            latent_new_diff = latent_new(1:end-1)./ latent_new(2:end)-1;

            bound = 0.085;

            hold(app.UIAxes,'on')
            plot(app.UIAxes , t ,  latent_new_diff(1:n_svd),'xr');             
            
            for i =1:length(latent_new_diff) -10  
                app.RankEditField.Value = i; 
                if (latent_new_diff(i) >= bound) &&all(latent_new_diff(i+1 : i+10)<bound )
                    break;
                end
            end 

            plot(app.UIAxes , t , repmat(bound , 1 ,n_svd),'k');
            hold(app.UIAxes,'off')
            app.RankEditField.Value = max(app.RankEditField.Value , 3);
            app.GaussiancorenumbreEditField.Value = app.RankEditField.Value + 1;
            
            Cout(app, sprintf('**It has finished calculating initial W and estimating rank.**\n The estimated rank is %d.',app.RankEditField.Value))
            
            
            app.StatusLamp.Color = [0,1,0];
            app.MAGICsmoothingCheckBox.Enable = 'on';
            app.AlphaEditField.Enable = 'on';
            app.BetaEditField.Enable = 'on';
            app.RankEditField.Enable = 'on';
            app.MaxiterEditField.Enable = 'on';
            app.RunSNMF_Button.Enable = 'on';
            app.toleranceEditField.Enable = 'on';
        end

        % Button pushed function: RunSNMF_Button
        function RunSNMF_ButtonPushed(app, event)
            
            app.StatusLamp.Color = [1,0,0]; 
            app.MAGICsmoothingCheckBox.Enable = 'off';
            Cout(app, '**It is performing SNMF.**')
            if ~isempty(app.UIAxes)
                cla(app.UIAxes);
            end
            pause(0.001)
            title(app.UIAxes, 'SNMF error')
            xlabel(app.UIAxes, 'iter')
            ylabel(app.UIAxes, 'error value')
            app.UIAxes.TitleFontWeight = 'bold';          
            app.UIAxes.XLim = [0 ,app.MaxiterEditField.Value + 1];
%             ClearPushed(app)    
          
%            opt = statset('MaxIter',100,'Display','final','TolFun',1e-6,'TolX',1e-7);
%            [app.W , app.H ,error] = nnmf(app.A_norm ,k,'w0',app.initial_W(:,1:k),'options',opt);  
        
            hold(app.UIAxes,'on')
            app.W = [];
            app.H = []; 
            [app.W , app.H ,error ,state] = SNMF_saveMemery_cpu(app);            
            hold(app.UIAxes ,'off') 
            
            if app.MAGICsmoothingCheckBox.Value == 1
                app.H = MAGIC_PCA(app)';
            end
            
            Cout(app, sprintf('The relative error of the SNMF is : %f ' ,error));         
            Cout(app, '**It is Finished SNMF.**')
            
            
            app.UIAxes.XLim = [0 ,inf];
            %plot the var of every feature 
%             title(app.UIAxes, 'var of SNMF feature' ,'Color', 'm');
%             xlabel(app.UIAxes, 'idx feature');
%             ylabel(app.UIAxes, 'var Value');
            var_H = std(app.H , 0 , 2);
%             t = 1:length(var_H) ;            
            bound = 0.4*mean(var_H);
            
            app.GaussiancorenumbreEditField.Value = sum(var_H >= bound) + 1;
            app.MAGICsmoothingCheckBox.Enable = 'on';
            app.StatusLamp.Color = [0,1,0];
            app.GaussiancorenumbreEditField.Enable = 'on';
            app.GMButton.Enable = 'on';
        end

        % Button pushed function: GMButton
        function GMButtonPushed(app, event)
            app.StatusLamp.Color = [1,0,0];
            app.eitimate_nSpectral = 0;
            Cout(app,'**It is calculating similarity matrix and Fielder vector.**')
            pause(0.001)
            caulecte_similarMat(app);
            Cout(app,'**It has Finished calculating similarity matrix and Fielder vector.**')
            app.StatusLamp.Color = [0,1,0];
            app.SpectralNumberEditField.Enable = 'on';
            app.LambdaEditField.Enable = 'on';
            app.ClusteringButton.Enable = 'on';
            app.MinimumgroupsizeEditField.Enable = 'on';
        end

        % Button pushed function: ClusteringButton
        function ClusteringButtonPushed(app, event)
            app.StatusLamp.Color = [1,0,0] ;
            
            nn_eigenVector = app.SpectralNumberEditField.Value;
            app.Fielder_vector = mean(app.eigenVector_SM(:,1:nn_eigenVector),2); 
            [x_old,x_index1] = sort( app.Fielder_vector); 
            t = 1:length(x_old);
            num_subClass = floor(app.LambdaEditField.Value);
            x_new = [];
            state_plus = 0;
            if app.eitimate_nSpectral ==0
                app.eitimate_nSpectral = 1;
                if num_subClass >= 1
                    title(app.UIAxes, 'Fielder Vector 1' ,'Color', 'm');
                    xlabel(app.UIAxes, 'idx feature');
                    ylabel(app.UIAxes, 'Value');
                    cla(app.UIAxes);
                    hold(app.UIAxes ,'on');
                    x_old  = x_old  - mean(x_old );
                    x_old = x_old /abs(min(x_old ))*2;
                    plot(app.UIAxes, t ,x_old,'xk');    
                    y = x_old(2:end) - x_old(1:end-1);
                    block_size =max(floor( 0.025*length(y)),5);
                    for j  = block_size : length(y)-block_size
                        if y(j,1) ~= max(y(j-block_size+1:j+block_size,1))
                            y(j) =0;
                        end
                    end
                    [~ , index_y]  = sort(y(block_size:end-block_size),'descend');
                    index_X_old = index_y(1:num_subClass-1)+ block_size-1;
                    subBound = 0.5 * (x_old(index_X_old) + x_old(index_X_old+1));
                    subBound = sort(subBound);
                    x_new(x_old<subBound(1)) = mean(x_old(x_old<subBound(1)));
                    for i = 2: num_subClass -1
                        x_new(x_old>=subBound(i-1)&x_old<subBound(i)) =...
                        mean(x_old(x_old>=subBound(i-1)&x_old<subBound(i)));                    
                    end
                    x_new(x_old>=subBound(num_subClass -1)) = mean(x_old(x_old>=subBound(num_subClass -1)));
                    x_index = x_index1;
                    
                    title(app.UIAxes, 'Fielder Vector' ,'Color', 'm');
                    xlabel(app.UIAxes, 'idx feature');
                    ylabel(app.UIAxes, 'Value');
                    cla(app.UIAxes);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t ,x_old,'xk');
                    plot(app.UIAxes, t ,x_new,'r');
                    hold(app.UIAxes,'off') 
                else 
                    addpath('C:\Software\cvx\builtins');
                    addpath('C:\Software\cvx\commands');
                    addpath('C:\Software\cvx\functions');
                    addpath('C:\Software\cvx\lib');  
                    addpath('C:\Software\cvx\structures');
                    addpath('C:\Software\cvx\functions\vec_');
                    addpath('C:\Software\cvx');
                    x_old  = x_old  - mean(x_old);
                    x_old = x_old /abs(min(x_old))*2;
                    title(app.UIAxes, 'Fielder Vector 1' ,'Color', 'm');
                    xlabel(app.UIAxes, 'idx feature');
                    ylabel(app.UIAxes, 'Value');
                    cla(app.UIAxes);
                    x_new1 = calcu_FielderVector(app,x_old,app.LambdaEditField.Value ,app.UIAxes);
                    x_new1 = roundn(x_new1 ,-3);%Rounding off
                    x_new = x_new1;
                    x_index = x_index1;
                    %判断nn_eigenVector+1个最小的奇异值向量的平均作为fielder向量是否可以得到更多的类
    %%{
                  if (app.SingleValue(nn_eigenVector + 1)-app.SingleValue(nn_eigenVector)  )< 0.03...%SNMF
                             &&nn_eigenVector < max(app.RankEditField.Value-1,3)                     
    %                  if (app.SingleValue(nn_eigenVector + 1)-app.singleValue_bound )< 0.017...%NMF
    %                         &&nn_eigenVector <= max(app.ReducedDimensionEditField.Value-2,3)
                        x_old = mean(app.eigenVector_SM(:,1:nn_eigenVector+1),2);
                        x_old  = x_old  - mean(x_old );
                        x_old = x_old /abs(min(x_old ))*2;
                        [x_old,x_index2] = sort(x_old);
                        title(app.UIAxes, 'Fielder Vector 2' ,'Color', 'm');
                        xlabel(app.UIAxes, 'idx feature');
                        ylabel(app.UIAxes, 'Value');
                        cla(app.UIAxes);
                        x_new2 = calcu_FielderVector(app,x_old,app.LambdaEditField.Value ,app.UIAxes);
                        x_new2= roundn(x_new2 ,-3);%Rounding off     
                        if length(unique(x_new2)) > length(unique(x_new1))              
                            state_plus = 1;
                            x_new = x_new2;  
                            x_index = x_index2;
                            app.SpectralNumberEditField.Value =  app.SpectralNumberEditField.Value +1;
                            pause(0.001)
                        end
                  end
    %}
         
    % %{
                 if state_plus == 0
                            x_old = mean(app.eigenVector_SM(:,1:app.SpectralNumberEditField.Value-1),2);
                 else
                            x_old = mean(app.eigenVector_SM(:,1:app.SpectralNumberEditField.Value-2),2);
                 end  
                 if (app.SpectralNumberEditField.Value -state_plus)>2&&(max(x_old) - min(x_old) > 1e-5)
            
                     x_old  = x_old  - mean(x_old );
                     x_old = x_old /abs(min(x_old ))*2;
                     [x_old,x_index2] = sort(x_old);
                     title(app.UIAxes, 'Fielder Vector 3' ,'Color', 'm');
                     xlabel(app.UIAxes, 'idx feature');
                     ylabel(app.UIAxes, 'Value');
                     cla(app.UIAxes);
                     x_new2 = calcu_FielderVector(app,x_old,app.LambdaEditField.Value ,app.UIAxes);
                     x_new2= roundn(x_new2 ,-3);%Rounding off 
                     if length(unique(x_new2)) >= length(unique(x_new))                             
                            x_new = x_new2;  
                            x_index = x_index2;
                            if state_plus == 0
                                app.SpectralNumberEditField.Value =  app.SpectralNumberEditField.Value -1;
                            else
                                app.SpectralNumberEditField.Value =  app.SpectralNumberEditField.Value -2;
                            end                            
                            pause(0.001)                                                         
                     end 
                     
                     
                 end
         %}         
                    title(app.UIAxes, 'Fielder Vector' ,'Color', 'm');
                    xlabel(app.UIAxes, 'idx feature');
                    ylabel(app.UIAxes, 'Value');
                    cla(app.UIAxes);
                    x_old = mean(app.eigenVector_SM(:,1:app.SpectralNumberEditField.Value),2);
                    x_old  = x_old  - mean(x_old );
                    x_old = x_old /abs(min(x_old ))*2;
                    [x_old,x_index] = sort(x_old);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t ,x_old,'xk');
                    plot(app.UIAxes, t ,x_new,'r');
                    hold(app.UIAxes,'off')
                    rmpath('C:\Software\cvx\builtins');
                    rmpath('C:\Software\cvx\commands');
                    rmpath('C:\Software\cvx\functions');
                    rmpath('C:\Software\cvx\lib');                
                    rmpath('C:\Software\cvx\structures');
                    rmpath('C:\Software\cvx\functions\vec_');
                    rmpath('C:\Software\cvx');  
                end        
   
            else
                if num_subClass >= 1
                    title(app.UIAxes, 'Fielder Vector 1' ,'Color', 'm');
                    xlabel(app.UIAxes, 'idx feature');
                    ylabel(app.UIAxes, 'Value');
                    cla(app.UIAxes);
                    x_old  = x_old  - mean(x_old );
                    x_old = x_old /abs(min(x_old ))*2;
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t ,x_old,'xk');
                    y = x_old(2:end) - x_old(1:end-1);
                    block_size =max(floor( 0.025*length(y)),5);
                    for j  = block_size : length(y)-block_size
                        if y(j,1) ~= max(y(j-block_size+1:j+block_size,1))
                            y(j) =0;
                        end
                    end
                    [~ , index_y]  = sort(y(block_size:end-block_size),'descend');
                    index_X_old = index_y(1:num_subClass-1)+ block_size-1;
                    subBound = 0.5 * (x_old(index_X_old) + x_old(index_X_old+1));
                    subBound = sort(subBound);
                    x_new(x_old<subBound(1)) = mean(x_old(x_old<subBound(1)));
                    for i = 2: num_subClass -1
                        x_new(x_old>=subBound(i-1)&x_old<subBound(i)) =...
                        mean(x_old(x_old>=subBound(i-1)&x_old<subBound(i)));                    
                    end
                    x_new(x_old>=subBound(num_subClass -1)) = mean(x_old(x_old>=subBound(num_subClass -1)));
                    x_index = x_index1;
                    
                    plot(app.UIAxes, t ,x_new,'r');
                    hold(app.UIAxes,'off') 
                else 
                    addpath('C:\Software\cvx\builtins');
                    addpath('C:\Software\cvx\commands');
                    addpath('C:\Software\cvx\functions');
                    addpath('C:\Software\cvx\lib');  
                    addpath('C:\Software\cvx\structures');
                    addpath('C:\Software\cvx\functions\vec_');
                    addpath('C:\Software\cvx');
                    x_old  = x_old  - mean(x_old);
                    x_old = x_old /abs(min(x_old))*2;
                    title(app.UIAxes, 'Fielder Vector 1' ,'Color', 'm');
                    xlabel(app.UIAxes, 'idx feature');
                    ylabel(app.UIAxes, 'Value');
                    cla(app.UIAxes);
                    x_new = calcu_FielderVector(app,x_old,app.LambdaEditField.Value ,app.UIAxes);
                    x_new = roundn(x_new ,-3);%Rounding off
                    x_index = x_index1;
                    rmpath('C:\Software\cvx\builtins');
                    rmpath('C:\Software\cvx\commands');
                    rmpath('C:\Software\cvx\functions');
                    rmpath('C:\Software\cvx\lib');                
                    rmpath('C:\Software\cvx\structures');
                    rmpath('C:\Software\cvx\functions\vec_');
                    rmpath('C:\Software\cvx'); 
                end
            end
%             figure
%             SNMF_visual(mean(app.eigenVectorSimilarMat(:,1:app.FeatureNumberEditField.Value),2), app.class_ ,app.mapClassIdToName,1)
            
            
             x_new_set = union(x_new ,[]);
             app.celltype_label = zeros(length(app.cellName),1);
             for i=1:length(x_index)
                 app.celltype_label(x_index(i),1) = find(x_new(i)==x_new_set);
             end             
%              [CelltypeName, ~,ref_Label] = unique(app.cellName,'stable');
%              [value_ARI,value_NMI,value_P] = test_resoult([app.celltype_label,ref_Label]);
%              Cout(app,sprintf('ARI: %f;NMI: %f;P_value: %f',value_ARI,value_NMI,value_P))
           
            app.StatusLamp.Color = [0,1,0] ;
            app.SaveButton.Enable = 'on';
        end

        % Button pushed function: Clear
        function ClearPushed(app, event)
            app.logFile = {'Output: '};  
            app.OutputListBox.Items = app.logFile;
            app.logFile_index = {'1'};
            app.OutputListBox.ItemsData = app.logFile_index;
            app.OutputListBox.Value = app.OutputListBox.ItemsData{end};
            scroll(app.OutputListBox,app.OutputListBox.ItemsData{end});
        end

        % Button pushed function: RunTSNEButton
        function RunTSNEButtonPushed(app, event)
            app.StatusLamp.Color = [1,0,0] ;
            rng(2019)
            Cout(app, 'It is calculating tsne ... ')
            
            app.mapped_A_by_tSNA = [];
            if isempty(app.mapped_A_by_tSNA)
                n_pca = min(floor(0.5*min(size(app.A_norm))),20);
                A =  full(app.A_norm);
                A = A.*  repmat(10000./(sum(A) + 0.000000001),size(A,1) ,1);
                [ ~,mappedX ,~] = pca(A','NumComponents',max(n_pca,3));
                app.mapped_A_by_tSNA = tsne(app.H','NumDimensions' ,2);
            end
%            app.ing_ = app.mapped_A_by_tSNA(app.celltype_label(:,1)==5,:);
%             app.mapped_A_by_tSNA = app.ing_;
            [CelltypeName, ~,ref_Label] = unique(app.cellName,'stable');
%             app.celltype_label = [app.celltype_label,ref_Label];
%             ref_Label = [ref_Label,ref_Label];
            figure           
            if isempty(app.celltype_label)               
                set(gcf, 'position', [200 200 800 800]);
                box on
                set(gca,'xtick',[],'ytick',[]);
                
                if length(unique(ref_Label))>50
                    ref_Label = ones(length(ref_label),1);
                    SNMF_visual(app, app.mapped_A_by_tSNA,  ref_Label); 
                else
                    SNMF_visual(app, app.mapped_A_by_tSNA,  ref_Label, CelltypeName);
                end                
                title('Reference Result')      
            else    
                subplot(1,2,1)
                if length(unique(ref_Label))>50
                    ref_Label = ones(length(ref_Label),1);
                    SNMF_visual(app, app.mapped_A_by_tSNA,  ref_Label); 
                else
                    SNMF_visual(app, app.mapped_A_by_tSNA,  ref_Label, CelltypeName);
                end    
                title('Reference Result')
                subplot(1,2,2)
                SNMF_visual(app, app.mapped_A_by_tSNA, app.celltype_label);  
                title('SMAFS Result')
            end
            app.StatusLamp.Color = [0,1,0] ;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.StatusLamp.Color = [1,0,0];           
            
            output_path = uigetdir;
            if isequal(output_path,0)
               message = 'Please select the folder of output file, and push "select folder button".';
               uialert(app.SMAFSUIFigure,message,'info',...
               'Icon','info');   
               app.SaveButton.Enable ='on';
               app.StatusLamp.Color = 'g';
               pause(0.001);
               return
            end
            output_path = [output_path,'/'];
            [CelltypeName, ~,ref_Label] = unique(app.cellName,'stable');
            
            Cout(app, 'It is saving results')
            mkdir([output_path,'label'])
            mkdir([output_path,'sub_data'])
            
            csvwrite([output_path,'label/ref.csv'],ref_Label);
            csvwrite([output_path,'label/0_label.csv'],app.celltype_label);
            fileID = fopen([output_path, '/sub_data/gene_name.csv'],'w');
            formatSpec1 = '%s\n'; 
            fprintf(fileID,formatSpec1, app.geneName{:});
            fclose(fileID);
%           gene_name = app.A_source_head(2:end,1);
            A_save = [(1:1:size(app.A_source,1))',app.A_source];
            for i = 1:max(app.celltype_label)
                    fileID = fopen([output_path, '/sub_data/bigclass_',num2str(i),'.csv'],'w');
                    index_ing = find(app.celltype_label==i);
                    formatSpec1 = [repmat('%s,' ,1,length(index_ing)),'%s\n'];                 
                    formatSpec2 = ['gene%d,',repmat('%f,' ,1,length(index_ing)-1),'%f\n'];   
                    colum_name = ['geneName',app.cellName(1,index_ing)];
                    fprintf(fileID,formatSpec1,colum_name{1,1:length(index_ing)+1});
                    fprintf(fileID,formatSpec2,A_save(:, [1 ; 1+index_ing])');
                    fclose(fileID);                    
            end
            Cout(app, sprintf('The results have saved in %s.',output_path))
            app.StatusLamp.Color = [0,1,0] ;
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
          app.UpperboundEditField.Value = 8.5;
          app.LowerboundEditField.Value = 0.1;
          app.RankEditField.Value = 0;
          app.AlphaEditField.Value = 0.05;
          app.BetaEditField.Value = 0.085;
          app.MaxiterEditField.Value = 500;
          app.toleranceEditField.Value = 1e-7;
          app.LambdaEditField.Value = 0.012;
          app.MinimumgroupsizeEditField.Value = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SMAFSUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {301, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMAFSUIFigure = uifigure('Visible', 'off');
            app.SMAFSUIFigure.Name = 'SMAFS';
            app.SMAFSUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SMAFSUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Console';
            app.CoarseorFineButtonGroup = uibuttongroup(app.LeftPanel);
            app.CoarseorFineButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoarseorFineButtonGroupSelectionChanged, true);
            app.CoarseorFineButtonGroup.Title = 'Coarse or Fine';
            app.CoarseButton = uiradiobutton(app.CoarseorFineButtonGroup);
            app.CoarseButton.Text = 'Coarse';
            app.CoarseButton.Value = true;
            app.FineButton = uiradiobutton(app.CoarseorFineButtonGroup);
            app.FineButton.Text = 'Fine';
            app.GenefilterandnormalizationPanel = uipanel(app.LeftPanel);
            app.GenefilterandnormalizationPanel.Title = 'Gene filter and normalization';
            app.UpperboundEditFieldLabel = uilabel(app.GenefilterandnormalizationPanel);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.GenefilterandnormalizationPanel, 'numeric');
            app.UpperboundEditField.Limits = [0 Inf];
            app.UpperboundEditField.Enable = 'off';
            app.UpperboundEditField.Value = 8.5;
            app.LowerboundEditFieldLabel = uilabel(app.GenefilterandnormalizationPanel);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.GenefilterandnormalizationPanel, 'numeric');
            app.LowerboundEditField.Limits = [0 Inf];
            app.LowerboundEditField.Enable = 'off';
            app.LowerboundEditField.Value = 0.1;
            app.RunNormalization_Button = uibutton(app.GenefilterandnormalizationPanel, 'push');
            app.RunNormalization_Button.ButtonPushedFcn = createCallbackFcn(app, @RunNormalization_ButtonPushed, true);
            app.RunNormalization_Button.Icon = 'run.png';
            app.RunNormalization_Button.Enable = 'off';
            app.RunNormalization_Button.Tooltip = {'Perform filter genes and normalization'};
            app.RunNormalization_Button.Text = '';
            app.SNMFdimensionalityreductionPanel = uipanel(app.LeftPanel);
            app.SNMFdimensionalityreductionPanel.Title = 'SNMF dimensionality reduction';
            app.AlphaEditFieldLabel = uilabel(app.SNMFdimensionalityreductionPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SNMFdimensionalityreductionPanel, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.Enable = 'off';
            app.AlphaEditField.Value = 0.05;
            app.BetaLabel = uilabel(app.SNMFdimensionalityreductionPanel);
            app.BetaLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SNMFdimensionalityreductionPanel, 'numeric');
            app.BetaEditField.Limits = [0 1];
            app.BetaEditField.Enable = 'off';
            app.BetaEditField.Value = 0.085;
            app.MAGICsmoothingCheckBox = uicheckbox(app.SNMFdimensionalityreductionPanel);
            app.MAGICsmoothingCheckBox.Enable = 'off';
            app.MAGICsmoothingCheckBox.Text = ' MAGIC smoothing';
            app.RunSNMF_Button = uibutton(app.SNMFdimensionalityreductionPanel, 'push');
            app.RunSNMF_Button.ButtonPushedFcn = createCallbackFcn(app, @RunSNMF_ButtonPushed, true);
            app.RunSNMF_Button.Icon = 'run.png';
            app.RunSNMF_Button.Enable = 'off';
            app.RunSNMF_Button.Tooltip = {'Run SNMF dimension reduction'};
            app.RunSNMF_Button.Text = '';
            app.RankEditFieldLabel = uilabel(app.SNMFdimensionalityreductionPanel);
            app.RankEditFieldLabel.Text = 'Rank';
            app.RankEditField = uieditfield(app.SNMFdimensionalityreductionPanel, 'numeric');
            app.RankEditField.Limits = [0 100];
            app.RankEditField.RoundFractionalValues = 'on';
            app.RankEditField.Enable = 'off';
            app.MaxiterEditFieldLabel = uilabel(app.SNMFdimensionalityreductionPanel);
            app.MaxiterEditFieldLabel.Text = 'Max iter';
            app.MaxiterEditField = uieditfield(app.SNMFdimensionalityreductionPanel, 'numeric');
            app.MaxiterEditField.Limits = [0 Inf];
            app.MaxiterEditField.RoundFractionalValues = 'on';
            app.MaxiterEditField.Enable = 'off';
            app.MaxiterEditField.Value = 500;
            app.toleranceEditFieldLabel = uilabel(app.SNMFdimensionalityreductionPanel);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.SNMFdimensionalityreductionPanel, 'numeric');
            app.toleranceEditField.Limits = [0 1];
            app.toleranceEditField.Enable = 'off';
            app.toleranceEditField.Value = 1e-07;
            app.calcu_initial_WButton = uibutton(app.SNMFdimensionalityreductionPanel, 'push');
            app.calcu_initial_WButton.ButtonPushedFcn = createCallbackFcn(app, @calcu_initial_WButtonPushed, true);
            app.calcu_initial_WButton.Enable = 'off';
            app.calcu_initial_WButton.Tooltip = {'Calculate the initial value of W'};
            app.calcu_initial_WButton.Text = 'calcu_initial_W';
            app.SpectralClusterPanel = uipanel(app.LeftPanel);
            app.SpectralClusterPanel.Title = 'Spectral Cluster';
            app.SpectralNumberEditFieldLabel = uilabel(app.SpectralClusterPanel);
            app.SpectralNumberEditFieldLabel.Text = 'Spectral Number';
            app.SpectralNumberEditField = uieditfield(app.SpectralClusterPanel, 'numeric');
            app.SpectralNumberEditField.Limits = [1 Inf];
            app.SpectralNumberEditField.RoundFractionalValues = 'on';
            app.SpectralNumberEditField.Enable = 'off';
            app.SpectralNumberEditField.Value = 1;
            app.GaussiancorenumbreEditFieldLabel = uilabel(app.SpectralClusterPanel);
            app.GaussiancorenumbreEditFieldLabel.Text = 'Gaussian core numbre';
            app.GaussiancorenumbreEditField = uieditfield(app.SpectralClusterPanel, 'numeric');
            app.GaussiancorenumbreEditField.Limits = [2 Inf];
            app.GaussiancorenumbreEditField.RoundFractionalValues = 'on';
            app.GaussiancorenumbreEditField.Enable = 'off';
            app.GaussiancorenumbreEditField.Value = 2;
            app.GMButton = uibutton(app.SpectralClusterPanel, 'push');
            app.GMButton.ButtonPushedFcn = createCallbackFcn(app, @GMButtonPushed, true);
            app.GMButton.Enable = 'off';
            app.GMButton.Tooltip = {'Calculate similarity matrix between cells using Gaussian-mixture model'};
            app.GMButton.Text = 'G-M';
            app.ClusteringButton = uibutton(app.SpectralClusterPanel, 'push');
            app.ClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringButtonPushed, true);
            app.ClusteringButton.Enable = 'off';
            app.ClusteringButton.Tooltip = {'Run Spectral Clustering'};
            app.ClusteringButton.Text = 'Clustering';
            app.LambdaEditFieldLabel = uilabel(app.SpectralClusterPanel);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.SpectralClusterPanel, 'numeric');
            app.LambdaEditField.Limits = [0 Inf];
            app.LambdaEditField.Enable = 'off';
            app.LambdaEditField.Value = 0.012;
            app.MinimumgroupsizeEditFieldLabel = uilabel(app.SpectralClusterPanel);
            app.MinimumgroupsizeEditFieldLabel.Text = 'Minimum group size';
            app.MinimumgroupsizeEditField = uieditfield(app.SpectralClusterPanel, 'numeric');
            app.MinimumgroupsizeEditField.Limits = [0 Inf];
            app.MinimumgroupsizeEditField.Enable = 'off';
            app.MinimumgroupsizeEditField.Tooltip = {'The smallest number of cells in a cluster. If setting it to 0,SMAFS will use defalt value.'; ''};
            app.ReadfilePanel = uipanel(app.LeftPanel);
            app.ReadfilePanel.Title = 'Read file';
            app.OpenButton = uibutton(app.ReadfilePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_Pushed, true);
            app.OpenButton.Icon = 'opened.png';
            app.OpenButton.Tooltip = {'Select file'};
            app.OpenButton.Text = '';
            app.UploadButton = uibutton(app.ReadfilePanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = 'upload.png';
            app.UploadButton.Enable = 'off';
            app.UploadButton.Tooltip = {'Upload file'};
            app.UploadButton.Text = '';
            app.DataformatDropDownLabel = uilabel(app.ReadfilePanel);
            app.DataformatDropDownLabel.Text = 'Data format';
            app.DataformatDropDown = uidropdown(app.ReadfilePanel);
            app.DataformatDropDown.Items = {'Matrix(gene * cell)', '10X'};
            app.DataformatDropDown.Tooltip = {'Matrix(gene * cell): a tsv or csv format file in which the first columns stores'; ' the gene names and the first row the cell names mandatorily .'; ''; 'The "10X" reads in the output of the cellranger pipeline from 10X including'; ' “matrix.mtx”，"barcodes.tsv''" and "genes.tsv"'};
            app.DataformatDropDown.Value = 'Matrix(gene * cell)';
            app.Reset = uibutton(app.ReadfilePanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Icon = 'reset.png';
            app.Reset.Tooltip = {'Setting model parameters to default values.'};
            app.Reset.Text = '';
            app.OthersPanel = uipanel(app.LeftPanel);
            app.OthersPanel.Title = 'Others';
            app.RunTSNEButton = uibutton(app.OthersPanel, 'push');
            app.RunTSNEButton.ButtonPushedFcn = createCallbackFcn(app, @RunTSNEButtonPushed, true);
            app.RunTSNEButton.Enable = 'off';
            app.RunTSNEButton.Tooltip = {'TSNE visualization'};
            app.RunTSNEButton.Text = 'TSNE';
            app.SaveButton = uibutton(app.OthersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'saver.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Save results'};
            app.SaveButton.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'The path of origin data';
            app.OutputListBox = uilistbox(app.RightPanel);
            app.OutputListBox.Items = {'Output:'};
            app.OutputListBox.Value = 'Output:';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Clear = uibutton(app.RightPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearPushed, true);
            app.Clear.Icon = 'clear.png';
            app.Clear.Text = '';
            app.StatusLamp = uilamp(app.RightPanel);
            app.StatusLampLabel = uilabel(app.RightPanel);
            app.StatusLampLabel.Text = 'Status';
            % Show the figure after all components are created
            app.SMAFSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNMFS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMAFSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMAFSUIFigure)
        end
    end
end